classdef raicesDeEcuaciones < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        principalPanel              matlab.ui.container.Panel
        RacesdeEcuacionesLabel      matlab.ui.control.Label
        CreadoresLabel              matlab.ui.control.Label
        empezarB                    matlab.ui.control.Button
        salirB                      matlab.ui.control.Button
        menu1Panel                  matlab.ui.container.Panel
        titulo_menu1                matlab.ui.control.Label
        homeB_menu1                 matlab.ui.control.Button
        calculadoraB_menu1          matlab.ui.control.Button
        evaluacionB_menu1           matlab.ui.control.Button
        ejerciciosB_menu1           matlab.ui.control.Button
        teoriaB_menu1               matlab.ui.control.Button
        teoriaPanel                 matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        BiseccionTab                matlab.ui.container.Tab
        tituloBiseccion             matlab.ui.control.Label
        RegulaFalsiTab              matlab.ui.container.Tab
        tituloRegula                matlab.ui.control.Label
        PuntoFijoTab                matlab.ui.container.Tab
        tituloPunto                 matlab.ui.control.Label
        NewtonRaphsonTab            matlab.ui.container.Tab
        tituloSecante               matlab.ui.control.Label
        SecanteTab                  matlab.ui.container.Tab
        tituloPunto_2               matlab.ui.control.Label
        RaicesMultiplesTab          matlab.ui.container.Tab
        tituloPunto_3               matlab.ui.control.Label
        ejerciciosPanel             matlab.ui.container.Panel
        TabGroup2                   matlab.ui.container.TabGroup
        BiseccionTab_2              matlab.ui.container.Tab
        tituloBiseccion_ejercicios  matlab.ui.control.Label
        RegulaFalsiTab_2            matlab.ui.container.Tab
        tituloRegula_ejercicios     matlab.ui.control.Label
        PuntoFijoTab_2              matlab.ui.container.Tab
        tituloPunto_ejercicios      matlab.ui.control.Label
        NewtonRaphsonTab_2          matlab.ui.container.Tab
        tituloNewton_ejercicios     matlab.ui.control.Label
        SecanteTab_2                matlab.ui.container.Tab
        tituloSecante_ejercicios    matlab.ui.control.Label
        RaicesMultiplesTab_2        matlab.ui.container.Tab
        tituloRaices_ejercicios     matlab.ui.control.Label
        calculadoraPanel            matlab.ui.container.Panel
        TecladoPanel                matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        VariableXButton             matlab.ui.control.Button
        LimpiarButton               matlab.ui.control.Button
        LimpTodoButton              matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        Button_5                    matlab.ui.control.Button
        Button_6                    matlab.ui.control.Button
        Button_7                    matlab.ui.control.Button
        Button_8                    matlab.ui.control.Button
        Button_9                    matlab.ui.control.Button
        Button_10                   matlab.ui.control.Button
        Button_11                   matlab.ui.control.Button
        Button_12                   matlab.ui.control.Button
        Button_13                   matlab.ui.control.Button
        SenButton                   matlab.ui.control.Button
        CosButton                   matlab.ui.control.Button
        TanButton                   matlab.ui.control.Button
        Button_14                   matlab.ui.control.Button
        Button_15                   matlab.ui.control.Button
        eButton                     matlab.ui.control.Button
        lnButton                    matlab.ui.control.Button
        RaiznButton                 matlab.ui.control.Button
        PotnButton                  matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        Panel_2                     matlab.ui.container.Panel
        IngreseFuncinLabel          matlab.ui.control.Label
        FxEditFieldLabel            matlab.ui.control.Label
        Funcion                     matlab.ui.control.EditField
        IntInferiorEditFieldLabel   matlab.ui.control.Label
        IntInferiorEditField        matlab.ui.control.NumericEditField
        IntSuperiorEditFieldLabel   matlab.ui.control.Label
        IntSuperiorEditField        matlab.ui.control.NumericEditField
        Panel_3                     matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        GraficarButton              matlab.ui.control.Button
        LimpiarButton_2             matlab.ui.control.Button
        Panel_4                     matlab.ui.container.Panel
        MetAbiertosDropDownLabel    matlab.ui.control.Label
        MetAbiertosDropDown         matlab.ui.control.DropDown
        MetCerradosDropDownLabel    matlab.ui.control.Label
        MetCerradosDropDown         matlab.ui.control.DropDown
        MtodosNumricosLabel         matlab.ui.control.Label
        LimInferiorEditFieldLabel   matlab.ui.control.Label
        LimInferiorEditField        matlab.ui.control.NumericEditField
        LimSuperiorEditFieldLabel   matlab.ui.control.Label
        LimSuperiorEditField        matlab.ui.control.NumericEditField
        ErrorTEditFieldLabel        matlab.ui.control.Label
        ErrorTEditField             matlab.ui.control.NumericEditField
        ResolverButton              matlab.ui.control.Button
        SolucionLabel               matlab.ui.control.Label
        Solucion                    matlab.ui.control.EditField
        UIAxes                      matlab.ui.control.UIAxes
        UITable                     matlab.ui.control.Table
        Panel_5                     matlab.ui.container.Panel
        RutaEditFieldLabel          matlab.ui.control.Label
        RutaEditField               matlab.ui.control.EditField
        NombreEditFieldLabel        matlab.ui.control.Label
        NombreEditField             matlab.ui.control.EditField
        ExtensinButtonGroup         matlab.ui.container.ButtonGroup
        csvButton                   matlab.ui.control.RadioButton
        xlsxButton                  matlab.ui.control.RadioButton
        txtButton                   matlab.ui.control.RadioButton
        ExportarButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: empezarB
        function empezarBButtonPushed(app, event)
            app.principalPanel.Visible = 'off';    %apagar el principal
            app.menu1Panel.Visible = 'on';         %encender el menu
            app.teoriaPanel.Visible = 'on';        %encender el panel de teoria
            
        end

        % Button pushed function: salirB
        function salirBButtonPushed(app, event)
            app.delete;
        end

        % Callback function
        function salirB_teoriaButtonPushed(app, event)
            %dont use
        end

        % Button pushed function: homeB_menu1
        function homeB_menu1ButtonPushed(app, event)
            app.principalPanel.Visible = 'on';
            app.menu1Panel.Visible = 'off';
            
            
            %apagar todas las demas ventaas here
            app.teoriaPanel.Visible = "off";           %mantener el panel de teoria off
            app.ejerciciosPanel.Visible = 'off';       %mantener el panel de calculadora off
            app.calculadoraPanel.Visible = 'off';      %mantener el panel de calculadora off
        end

        % Button pushed function: teoriaB_menu1
        function teoriaB_menu1ButtonPushed(app, event)
            
            app.titulo_menu1.Text = "Teoría";         %cambiar el nombre del titulo
            
            app.principalPanel.Visible = 'off';
            app.menu1Panel.Visible = 'on';            %mantener encendido el menu
            app.teoriaPanel.Visible = "on";           %mantener el panel de teoria on
            
            %apagar todas las demas ventaas here
            app.ejerciciosPanel.Visible = "off";       %mantener el panel de teoria off
            app.calculadoraPanel.Visible = 'off';      %mantener el panel de calculadora off
        end

        % Button pushed function: ejerciciosB_menu1
        function ejerciciosB_menu1ButtonPushed(app, event)
            
            app.titulo_menu1.Text = "Ejercicios";     %cambiar el nombre del titulo
            
            app.principalPanel.Visible = 'off';
            app.menu1Panel.Visible = 'on';            %mantener encendido el menu
            app.ejerciciosPanel.Visible = 'on';       %mantener encendido el de ejercicios
            
            app.teoriaPanel.Visible = "off";          %mantener el panel de teoria off
            app.calculadoraPanel.Visible = 'off';      %mantener el panel de calculadora off
        end

        % Button pushed function: calculadoraB_menu1
        function calculadoraB_menu1ButtonPushed(app, event)
            
            app.titulo_menu1.Text = "Calculadora";    %cambiar el nombre del titulo
            
            app.principalPanel.Visible = 'off';
            app.calculadoraPanel.Visible = 'on';      %mantener encendido la calculadora
            
            app.teoriaPanel.Visible = "off";          %mantener el panel de teoria off
            app.ejerciciosPanel.Visible = 'off';      %mantener el panel de ejercicios off

        end

        % Button pushed function: VariableXButton
        function VariableXButtonPushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"x");
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"1");
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"2");
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"3");
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"+");
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"-");
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"4");
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"5");
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"6");
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,".*");
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"7");
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"8");
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"9");
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"./");
        end

        % Button pushed function: SenButton
        function SenButtonPushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'sin(');
        end

        % Button pushed function: CosButton
        function CosButtonPushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'cos(');
        end

        % Button pushed function: TanButton
        function TanButtonPushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'tan(');
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
             cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'(');
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
             cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,')');
        end

        % Button pushed function: eButton
        function eButtonPushed(app, event)
              cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'exp(');
        end

        % Button pushed function: lnButton
        function lnButtonPushed(app, event)
              cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'ln(');
        end

        % Button pushed function: RaiznButton
        function RaiznButtonPushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'.^(1/');
        end

        % Button pushed function: PotnButton
        function PotnButtonPushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'.^');
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            ini = char (' ');
            app.Funcion.Value=strcat(ini);
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            funcion = app.Funcion.Value;
            intervalo = [app.IntInferiorEditField.Value app.IntSuperiorEditField.Value];
                       
            if ~isequal('',funcion)
                fplot(app.UIAxes,funcion,intervalo);
            end
            
        end

        % Button pushed function: ResolverButton
        function ResolverButtonPushed(app, event)
            funcion = app.Funcion.Value;
            limiInf = app.LimInferiorEditField.Value;
            limiSup = app.LimSuperiorEditField.Value;
            tole   = app.ErrorTEditField.Value;
            metAbier = app.MetAbiertosDropDown.Value;
            metCerr  = app.MetCerradosDropDown.Value;
            
            if isequal('',metCerr) && ~isequal('',metAbier)
                
                if isequal('Punto Fijo', metAbier) %punto fijo
                    app.Solucion.Value = "MA";
                elseif isequal('Newton-Raphson', metAbier) %Newton
                    syms x;
                    x0 = limiInf;
                    f = inline(funcion);
                    dxf = inline(diff(str2sym(funcion),x));
                    N=2;
                    tol = tole; %field numeric
                    maxDiv = 10000;
                    x =x0;
                    tab = {'i','x','f(x)','n(x)','error'};
                    while (N<10000)
                        xn = x -f(x)/dxf(x );
                        tab(N,:) = {N-1,x,f(x),xn,abs(f(x))};
                        if abs(f(xn))<tol
                            x =xn;
                            iter=100-N;
                            app.Solucion.Value = num2str(x);
                            set(handles.uitable,'Data',tab); %tabla
                            drawnow;
                            break;
                        end
                        if abs(f(x ))>maxDiv
                            app.Solucion.Value='Se alcanzo el numero maximo de iteraciones';
                            set(handles.uitable,'Data',tab); %tabla
                            drawnow;
                            break;
                        end
                        N = N + 1;
                        x = xn;
                    end
        if N == 10000
         app.Solucion.Value='La solucion no converge';
        end
                elseif isequal('Secante', metAbier) %Secante
                    fun = inline(funcion);
                    a = limiInf; %field numeric
                    b = limiSup; %field numeric
                    tol = tole;
                    
                    u = fun(a);
                    v = fun(b);
                    c=2;
                    p0=a;
                    p1=b;
                    
                    %f=uifigure;
                    tab = {'i','x','x-1','x+1','f(x)','f(x-1)','error'};
                    
                    while abs(u) > tol
                        tab(c,:) = {c,p0,p1,(p0-u*(p1-p0)/(v-u)),u,v,abs(u)};
                        p=p0-u*(p1-p0)/(v-u);
                        p0=p;
                        p1=b;
                        u=fun(p0);
                        v=fun(p1);
                        c=c+1
                    end
                    app.Solucion.Value = num2str(p0);
                    %app.UITable('Data',tab);
                    
                elseif isequal('Raic. Multiples', metAbier) %Raic Multiple
                    app.Solucion.Value = "raic";
                end
                           
            elseif ~isequal('',metCerr) && isequal('',metAbier)
                
                if isequal('Bisección',metCerr) %biseccion
                    fun = inline(funcion);
                    a = limiInf;
                    b = limiSup;
                    tol = str2double(tole);
                    tab = {'a','b','m','f(a)','f(b)','f(m)','error'};
                    %uitable
                    if a>b
                        aa=a ;
                        a=b ;
                        b=aa ;
                    end 
                    xm=(a+b ) / 2 ;
                    if fun ( a ) *fun ( b )>0 
                        app.Solucion.Value='ERROR: Revise el intervalo';
                        drawnow;
                    elseif abs (b - a )<tol
                        raiz=xm ;
                    else
                        cont=2;
                        while ( ( fun ( xm ) ~=0)&& abs (b - a )>tol )
                            if fun ( xm ) *fun ( b )<0
                                a=xm ;
                            else
                                b=xm ;
                            end 
                            xm=(a+b ) / 2 ;
                            tab(cont,:) = {a,b,xm,fun(a),fun(b),fun(b),abs(b-a)};
                            cont = cont+1;
                        end
                        raiz=xm ;
                    end
                    app.Solucion.Value= num2str(raiz);
                    set(handles.uitable,'Data',tab); %tabla
                    drawnow;
                    
                    
                elseif isequal('Regla Falsa',metCerr) %Regla falsa
                     Fx = funcion;
                     a = limiInf;
                     c = limiSup;
                     e = str2double(tole);
                     
                     x=a;
                     Fa=eval(Fx);
                     x=c;
                     Fc=eval(Fx);
                     cont=1;
                     tab = {'i','a','b','f(a)','f(b)','c','f(c)','error'};
                     while abs(c-a)>e
                         cont = cont+1;
                         b=(c*Fa-a*Fc)/(Fa-Fc);
                         x=b;
                         Fb=eval(Fx);
                         tab(cont,:) = {cont,a,b,Fa,Fb,c,Fc,abs(c-a)};
                         if abs(Fc)<e
                             break;
                         else    
                             if Fa*Fb<=0
                                 c=b;
                                 Fc=Fb;
                             else
                                 a=b;
                                 Fa=Fb;
                             end
                         end
                     end
                     app.Solucion.Value= num2str(b);
                     set(handles.uitable,'Data',tab);
                     drawnow;
                end
            end          


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.principalPanel = uipanel(app.UIFigure);
            app.principalPanel.Tag = 'principal';
            app.RacesdeEcuacionesLabel = uilabel(app.principalPanel);
            app.RacesdeEcuacionesLabel.Text = {'Raíces de '; 'Ecuaciones'};
            app.CreadoresLabel = uilabel(app.principalPanel);
            app.CreadoresLabel.Text = {'Creadores:'; '  Sergio Yhoel Soncco Cahui.  '; '  Jhonatan Acuña Huisacayna'; '  Bryan Gallegos Batallanos'; '  Frank Ccapa Usca'; '  Luis Flores Bailon'};
            app.empezarB = uibutton(app.principalPanel, 'push');
            app.empezarB.ButtonPushedFcn = createCallbackFcn(app, @empezarBButtonPushed, true);
            app.empezarB.Tag = 'empezarB';
            app.empezarB.Text = 'Empezar';
            app.salirB = uibutton(app.principalPanel, 'push');
            app.salirB.ButtonPushedFcn = createCallbackFcn(app, @salirBButtonPushed, true);
            app.salirB.Text = 'Salir';
            app.menu1Panel = uipanel(app.UIFigure);
            app.menu1Panel.Visible = 'off';
            app.menu1Panel.Tag = 'menu1';
            app.titulo_menu1 = uilabel(app.menu1Panel);
            app.titulo_menu1.Tag = 'titulo_menu1';
            app.titulo_menu1.Text = 'Teoría';
            app.homeB_menu1 = uibutton(app.menu1Panel, 'push');
            app.homeB_menu1.ButtonPushedFcn = createCallbackFcn(app, @homeB_menu1ButtonPushed, true);
            app.homeB_menu1.Tag = 'homeB_menu1';
            app.homeB_menu1.Text = 'Home';
            app.calculadoraB_menu1 = uibutton(app.menu1Panel, 'push');
            app.calculadoraB_menu1.ButtonPushedFcn = createCallbackFcn(app, @calculadoraB_menu1ButtonPushed, true);
            app.calculadoraB_menu1.Tag = 'calculadoraB_menu1';
            app.calculadoraB_menu1.Text = 'Calculadora';
            app.evaluacionB_menu1 = uibutton(app.menu1Panel, 'push');
            app.evaluacionB_menu1.Tag = 'evaluacionB_menu1';
            app.evaluacionB_menu1.Text = 'Evaluación';
            app.ejerciciosB_menu1 = uibutton(app.menu1Panel, 'push');
            app.ejerciciosB_menu1.ButtonPushedFcn = createCallbackFcn(app, @ejerciciosB_menu1ButtonPushed, true);
            app.ejerciciosB_menu1.Tag = 'ejerciciosB_menu1';
            app.ejerciciosB_menu1.Text = 'Ejercicios';
            app.teoriaB_menu1 = uibutton(app.menu1Panel, 'push');
            app.teoriaB_menu1.ButtonPushedFcn = createCallbackFcn(app, @teoriaB_menu1ButtonPushed, true);
            app.teoriaB_menu1.Tag = 'teoriaB_menu1';
            app.teoriaB_menu1.Text = 'Teoría';
            app.teoriaPanel = uipanel(app.UIFigure);
            app.teoriaPanel.Visible = 'off';
            app.TabGroup = uitabgroup(app.teoriaPanel);
            app.BiseccionTab = uitab(app.TabGroup);
            app.BiseccionTab.Title = ' Biseccion  ';
            app.BiseccionTab.Tag = 'biseccionTab_teoria';
            app.tituloBiseccion = uilabel(app.BiseccionTab);
            app.tituloBiseccion.Tag = 'titulo_menu1';
            app.tituloBiseccion.Text = 'Método cerrado Bisección ';
            app.RegulaFalsiTab = uitab(app.TabGroup);
            app.RegulaFalsiTab.Title = ' Regula Falsi ';
            app.RegulaFalsiTab.Tag = 'regulaTab_teoria';
            app.tituloRegula = uilabel(app.RegulaFalsiTab);
            app.tituloRegula.Tag = 'titulo_menu1';
            app.tituloRegula.Text = 'Método cerrado Regula Falsi';
            app.PuntoFijoTab = uitab(app.TabGroup);
            app.PuntoFijoTab.Title = ' Punto Fijo ';
            app.PuntoFijoTab.Tag = 'puntoTab_teoria';
            app.tituloPunto = uilabel(app.PuntoFijoTab);
            app.tituloPunto.Tag = 'titulo_menu1';
            app.tituloPunto.Text = 'Método abierto Punto fijo ';
            app.NewtonRaphsonTab = uitab(app.TabGroup);
            app.NewtonRaphsonTab.Title = ' Newton-Raphson ';
            app.NewtonRaphsonTab.Tag = 'newtonTab_teoria';
            app.tituloSecante = uilabel(app.NewtonRaphsonTab);
            app.tituloSecante.Tag = 'titulo_menu1';
            app.tituloSecante.Text = 'Método abierto Newton-Raphson';
            app.SecanteTab = uitab(app.TabGroup);
            app.SecanteTab.Title = ' Secante ';
            app.SecanteTab.Tag = 'secanteTab_teoria';
            app.tituloPunto_2 = uilabel(app.SecanteTab);
            app.tituloPunto_2.Tag = 'titulo_menu1';
            app.tituloPunto_2.Text = 'Método abierto de la Secante';
            app.RaicesMultiplesTab = uitab(app.TabGroup);
            app.RaicesMultiplesTab.Title = ' Raices Multiples ';
            app.RaicesMultiplesTab.Tag = 'raicesTab_teoria';
            app.tituloPunto_3 = uilabel(app.RaicesMultiplesTab);
            app.tituloPunto_3.Tag = 'titulo_menu1';
            app.tituloPunto_3.Text = 'Método abierto de Raices Multiples ';
            app.ejerciciosPanel = uipanel(app.UIFigure);
            app.ejerciciosPanel.Visible = 'off';
            app.TabGroup2 = uitabgroup(app.ejerciciosPanel);
            app.BiseccionTab_2 = uitab(app.TabGroup2);
            app.BiseccionTab_2.Title = ' Biseccion  ';
            app.tituloBiseccion_ejercicios = uilabel(app.BiseccionTab_2);
            app.tituloBiseccion_ejercicios.Tag = 'titulo_menu1';
            app.tituloBiseccion_ejercicios.Text = 'Método cerrado Bisección ';
            app.RegulaFalsiTab_2 = uitab(app.TabGroup2);
            app.RegulaFalsiTab_2.Title = ' Regula Falsi ';
            app.tituloRegula_ejercicios = uilabel(app.RegulaFalsiTab_2);
            app.tituloRegula_ejercicios.Tag = 'titulo_menu1';
            app.tituloRegula_ejercicios.Text = 'Método cerrado Regula Falsi';
            app.PuntoFijoTab_2 = uitab(app.TabGroup2);
            app.PuntoFijoTab_2.Title = ' Punto Fijo ';
            app.tituloPunto_ejercicios = uilabel(app.PuntoFijoTab_2);
            app.tituloPunto_ejercicios.Tag = 'titulo_menu1';
            app.tituloPunto_ejercicios.Text = 'Método abierto Punto Fijo ';
            app.NewtonRaphsonTab_2 = uitab(app.TabGroup2);
            app.NewtonRaphsonTab_2.Title = ' Newton-Raphson ';
            app.tituloNewton_ejercicios = uilabel(app.NewtonRaphsonTab_2);
            app.tituloNewton_ejercicios.Tag = 'titulo_menu1';
            app.tituloNewton_ejercicios.Text = 'Método abierto Newton-Raphson ';
            app.SecanteTab_2 = uitab(app.TabGroup2);
            app.SecanteTab_2.Title = ' Secante ';
            app.tituloSecante_ejercicios = uilabel(app.SecanteTab_2);
            app.tituloSecante_ejercicios.Tag = 'titulo_menu1';
            app.tituloSecante_ejercicios.Text = 'Método abierto de la Secante ';
            app.RaicesMultiplesTab_2 = uitab(app.TabGroup2);
            app.RaicesMultiplesTab_2.Title = 'Raices Multiples';
            app.tituloRaices_ejercicios = uilabel(app.RaicesMultiplesTab_2);
            app.tituloRaices_ejercicios.Tag = 'titulo_menu1';
            app.tituloRaices_ejercicios.Text = 'Método abierto Raíces Múltiples ';
            app.calculadoraPanel = uipanel(app.UIFigure);
            app.calculadoraPanel.Visible = 'off';
            app.TecladoPanel = uipanel(app.calculadoraPanel);
            app.TecladoPanel.Title = 'Teclado';
            app.GridLayout = uigridlayout(app.TecladoPanel);
            app.VariableXButton = uibutton(app.GridLayout, 'push');
            app.VariableXButton.ButtonPushedFcn = createCallbackFcn(app, @VariableXButtonPushed, true);
            app.VariableXButton.Text = 'Variable X';
            app.LimpiarButton = uibutton(app.GridLayout, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.LimpTodoButton = uibutton(app.GridLayout, 'push');
            app.LimpTodoButton.Text = 'Limp. Todo';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '1';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '2';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '3';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '-';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '4';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '5';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '6';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '*';
            app.Button_10 = uibutton(app.GridLayout, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '7';
            app.Button_11 = uibutton(app.GridLayout, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '8';
            app.Button_12 = uibutton(app.GridLayout, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '9';
            app.Button_13 = uibutton(app.GridLayout, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '/';
            app.SenButton = uibutton(app.GridLayout, 'push');
            app.SenButton.ButtonPushedFcn = createCallbackFcn(app, @SenButtonPushed, true);
            app.SenButton.Text = 'Sen';
            app.CosButton = uibutton(app.GridLayout, 'push');
            app.CosButton.ButtonPushedFcn = createCallbackFcn(app, @CosButtonPushed, true);
            app.CosButton.Text = 'Cos';
            app.TanButton = uibutton(app.GridLayout, 'push');
            app.TanButton.ButtonPushedFcn = createCallbackFcn(app, @TanButtonPushed, true);
            app.TanButton.Text = 'Tan';
            app.Button_14 = uibutton(app.GridLayout, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '(';
            app.Button_15 = uibutton(app.GridLayout, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = ')';
            app.eButton = uibutton(app.GridLayout, 'push');
            app.eButton.ButtonPushedFcn = createCallbackFcn(app, @eButtonPushed, true);
            app.eButton.Text = 'e';
            app.lnButton = uibutton(app.GridLayout, 'push');
            app.lnButton.ButtonPushedFcn = createCallbackFcn(app, @lnButtonPushed, true);
            app.lnButton.Text = 'ln';
            app.RaiznButton = uibutton(app.GridLayout, 'push');
            app.RaiznButton.ButtonPushedFcn = createCallbackFcn(app, @RaiznButtonPushed, true);
            app.RaiznButton.Text = 'Raiz n';
            app.PotnButton = uibutton(app.GridLayout, 'push');
            app.PotnButton.ButtonPushedFcn = createCallbackFcn(app, @PotnButtonPushed, true);
            app.PotnButton.Text = 'Pot. n';
            app.Panel = uipanel(app.calculadoraPanel);
            app.Panel_2 = uipanel(app.Panel);
            app.IngreseFuncinLabel = uilabel(app.Panel_2);
            app.IngreseFuncinLabel.Text = 'Ingrese Función';
            app.FxEditFieldLabel = uilabel(app.Panel_2);
            app.FxEditFieldLabel.Text = 'F(x)=';
            app.Funcion = uieditfield(app.Panel_2, 'text');
            app.IntInferiorEditFieldLabel = uilabel(app.Panel_2);
            app.IntInferiorEditFieldLabel.Text = 'Int. Inferior';
            app.IntInferiorEditField = uieditfield(app.Panel_2, 'numeric');
            app.IntInferiorEditField.Value = -10;
            app.IntSuperiorEditFieldLabel = uilabel(app.Panel_2);
            app.IntSuperiorEditFieldLabel.Text = 'Int. Superior';
            app.IntSuperiorEditField = uieditfield(app.Panel_2, 'numeric');
            app.IntSuperiorEditField.Value = 10;
            app.Panel_3 = uipanel(app.Panel_2);
            app.GridLayout2 = uigridlayout(app.Panel_3);
            app.GraficarButton = uibutton(app.GridLayout2, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.LimpiarButton_2 = uibutton(app.GridLayout2, 'push');
            app.LimpiarButton_2.Text = 'Limpiar';
            app.Panel_4 = uipanel(app.Panel);
            app.MetAbiertosDropDownLabel = uilabel(app.Panel_4);
            app.MetAbiertosDropDownLabel.Text = 'Met. Abiertos';
            app.MetAbiertosDropDown = uidropdown(app.Panel_4);
            app.MetAbiertosDropDown.Items = {'', 'Punto Fijo', 'Newton-Raphson', 'Secante', 'Raic. Multiples'};
            app.MetAbiertosDropDown.Value = '';
            app.MetCerradosDropDownLabel = uilabel(app.Panel_4);
            app.MetCerradosDropDownLabel.Text = 'Met. Cerrados';
            app.MetCerradosDropDown = uidropdown(app.Panel_4);
            app.MetCerradosDropDown.Items = {'', 'Bisección', 'Regla Falsa'};
            app.MetCerradosDropDown.Value = '';
            app.MtodosNumricosLabel = uilabel(app.Panel_4);
            app.MtodosNumricosLabel.Text = 'Métodos Numéricos';
            app.LimInferiorEditFieldLabel = uilabel(app.Panel_4);
            app.LimInferiorEditFieldLabel.Text = 'Lim. Inferior';
            app.LimInferiorEditField = uieditfield(app.Panel_4, 'numeric');
            app.LimSuperiorEditFieldLabel = uilabel(app.Panel_4);
            app.LimSuperiorEditFieldLabel.Text = 'Lim. Superior';
            app.LimSuperiorEditField = uieditfield(app.Panel_4, 'numeric');
            app.ErrorTEditFieldLabel = uilabel(app.Panel_4);
            app.ErrorTEditFieldLabel.Text = 'Error T: ';
            app.ErrorTEditField = uieditfield(app.Panel_4, 'numeric');
            app.ErrorTEditField.Value = 0.001;
            app.ResolverButton = uibutton(app.Panel_4, 'push');
            app.ResolverButton.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.ResolverButton.Text = 'Resolver';
            app.SolucionLabel = uilabel(app.Panel_4);
            app.SolucionLabel.Text = 'Solucion';
            app.Solucion = uieditfield(app.Panel_4, 'text');
            app.Solucion.Editable = 'off';
            app.UIAxes = uiaxes(app.calculadoraPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.calculadoraPanel);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.Panel_5 = uipanel(app.calculadoraPanel);
            app.RutaEditFieldLabel = uilabel(app.Panel_5);
            app.RutaEditFieldLabel.Text = 'Ruta';
            app.RutaEditField = uieditfield(app.Panel_5, 'text');
            app.NombreEditFieldLabel = uilabel(app.Panel_5);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.Panel_5, 'text');
            app.ExtensinButtonGroup = uibuttongroup(app.Panel_5);
            app.ExtensinButtonGroup.Title = 'Extensión';
            app.csvButton = uiradiobutton(app.ExtensinButtonGroup);
            app.csvButton.Text = '.csv';
            app.csvButton.Value = true;
            app.xlsxButton = uiradiobutton(app.ExtensinButtonGroup);
            app.xlsxButton.Text = '.xlsx';
            app.txtButton = uiradiobutton(app.ExtensinButtonGroup);
            app.txtButton.Text = '.txt';
            app.ExportarButton = uibutton(app.Panel_5, 'push');
            app.ExportarButton.Text = 'Exportar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = raicesDeEcuaciones

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end